classdef SIGNAL_PROJECT_LAST_UPDATE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Sampling_frequency_of_signalEditFieldLabel  matlab.ui.control.Label
        Sampling_frequency_of_signalEditField  matlab.ui.control.NumericEditField
        Start_time_of_signalEditFieldLabel  matlab.ui.control.Label
        Start_time_of_signalEditField  matlab.ui.control.NumericEditField
        End_time_of_signalEditFieldLabel  matlab.ui.control.Label
        End_time_of_signalEditField    matlab.ui.control.NumericEditField
        number_of_break_points_of_signalEditFieldLabel  matlab.ui.control.Label
        number_of_break_points_of_signalEditField  matlab.ui.control.NumericEditField
        SaveButton                     matlab.ui.control.Button
        SIGNALDropDownLabel            matlab.ui.control.Label
        SIGNALDropDown                 matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
        uvalue_for_clippingEditFieldLabel  matlab.ui.control.Label
        uvalue_for_clippingEditField   matlab.ui.control.NumericEditField
        lvalue_for_clippingEditFieldLabel  matlab.ui.control.Label
        lvalue_for_clippingEditField   matlab.ui.control.NumericEditField
        UIAxes2                        matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Sampling_frequency_of_signal=double(app.Sampling_frequency_of_signalEditField.Value)
            Start_time_of_signal=double(app.Start_time_of_signalEditField.Value)
            end_time_of_signal=double(app.End_time_of_signalEditField.Value)
            number_of_break_points_of_signal=double(app.number_of_break_points_of_signalEditField.Value)
            
             % Input validation
            while isnan(Sampling_frequency_of_signal) || isnan(Start_time_of_signal) || isnan(end_time_of_signal) || isnan(number_of_break_points_of_signal)
                % Show error message
                errordlg('Please enter valid numeric values for all inputs.', 'Input Error', 'modal');
                return;
            end
            
             % Ask the user to input transition times
          % Initialize transition times array
transition_time = zeros(1, number_of_break_points_of_signal + 2);
transition_time(1) = Start_time_of_signal;  % Start time
transition_time(end) = end_time_of_signal;  % End time

% Collect break points
for i = 1:number_of_break_points_of_signal
    prompt = sprintf('Enter the time of break point #%d: ', i);
    temp_time = str2double(inputdlg(prompt, 'Break Point Time', [1 50]));
    
    % Input validation for break points
    while isnan(temp_time) || temp_time <= Start_time_of_signal || temp_time >= end_time_of_signal
        errordlg(sprintf('Invalid break point time; must be within (%.2f, %.2f).', Start_time_of_signal, end_time_of_signal), 'Input Error', 'modal');
        temp_time = str2double(inputdlg(prompt, 'Break Point Time', [1 50]));
    end
    transition_time(i + 1) = temp_time;
end
transition_time = sort(transition_time);  % Ensure transition times are ordered

% Loop through each segment defined by transition times
signal = [];
time_vector = [];
for j = 1:(length(transition_time) - 1)
    %fprintf('For the signal segment between %.2f and %.2f:\n', transition_time(j), transition_time(j+1));
    
    if number_of_break_points_of_signal ~= 0
        % Ask the user to select the signal type for this segment
        choice = listdlg('PromptString', 'Select a signal type:', ...
                         'SelectionMode', 'single', ...
                         'ListString', {'DC_SIGNAL', 'RAMP_SIGNAL', 'POLYNOMIAL', 'EXPONENTIAL', 'SIN_SIGNAL', 'SINC_FUNCTION', 'TRIANGLE_PULSE'});
        if isempty(choice)
            msgbox('No signal type selected, skipping this segment.');
            continue;  % Skip this iteration if the user cancels the dialog
        end
        valid_choices = {'DC_SIGNAL', 'RAMP_SIGNAL', 'POLYNOMIAL', 'EXPONENTIAL', 'SIN_SIGNAL', 'SINC_FUNCTION', 'TRIANGLE_PULSE'};
        choice = valid_choices{choice};  % Translate index to string
    else
        % Default behavior when no breakpoints are specified
         choice = app.SIGNALDropDown.Value; % Assume the operation is selected through dropdown
    end

    % Calculate segment duration and number of samples
    segment_duration = transition_time(j+1) - transition_time(j);
    number_of_samples = round(segment_duration * Sampling_frequency_of_signal);
    time_segment = linspace(transition_time(j), transition_time(j+1), number_of_samples);
    time_vector = [time_vector, time_segment(1:end-1)];  % Append time vector except the last point to avoid overlap

                switch choice 
                    case 'DC_SIGNAL'
                        dc_amplitude = str2double(inputdlg('Enter the amplitude of the DC signal: ', 'Input Dialog'));
                        dc_signal = dc_amplitude * ones(1, number_of_samples-1);
                        signal = [signal dc_signal];
                    case 'RAMP_SIGNAL'
                        ramp_slope = str2double(inputdlg('Enter the slope of the ramp signal: ', 'Input Dialog'));
                        ramp_intercept = str2double(inputdlg('Enter the intercept of the ramp signal: '));
                        ramp_signal = ramp_slope * (time_segment - time_segment(1)) + ramp_intercept;
                        signal = [signal ramp_signal(1:end-1)];
                   case 'POLYNOMIAL'
                          coeffStr = inputdlg('Enter the coefficients of the polynomial (in descending powers): ', 'Input Dialog');
                          coeffStrSplit = strsplit(coeffStr{1});  
                          coeff = str2double(coeffStrSplit);
                          polynomial_signal = polyval(coeff, time_segment - time_segment(1));
                          signal = [signal polynomial_signal(1:end-1)];
                    case 'EXPONENTIAL'
                        amplitude = str2double(inputdlg('Enter the amplitude of the exponential signal: ', 'Input Dialog'));
                        exponent = str2double(inputdlg('Enter the exponent of the exponential signal: ', 'Input Dialog'));
                        exponential_signal = amplitude * exp(exponent * (time_segment - time_segment(1)));
                        signal = [signal exponential_signal(1:end-1)];
                    case 'SIN_SIGNAL'
                        amplitude = str2double(inputdlg('Enter the amplitude of sine signal:', 'Input Dialog'));
                        frequency = str2double(inputdlg('Enter the frequency of the sinusoidal signal: ','Input Dialog'));
                        phase = str2sym(inputdlg('Enter the phase in radians: ','Input Dialog'));
                        sinusoidal_signal = amplitude * sin(2 * pi * frequency * (time_segment - time_segment(1)) + phase);
                        signal = [signal sinusoidal_signal(1:end-1)];
                    case 'SINC_FUNCTION'
                        amplitude = str2double(inputdlg('Enter the amplitude of the sinc function: ', 'Input Dialog'));
                        sinc_signal = amplitude * custom_sinc(pi * (time_segment - time_segment(1)));
                        signal = [signal sinc_signal(1:end-1)];    
                     case 'TRIANGLE_PULSE'
                        amplitude = str2double(inputdlg('Enter the amplitude of the triangle pulse: ', 'Input Dialog'));
                        centerShift = str2double(inputdlg('Enter the center shift: ', 'Input Dialog'));
                        width = str2double(inputdlg('Enter the width of the triangle pulse: '));  
                        triangle_signal =amplitude * max(1 - 2*abs((time_segment - centerShift) / width), 0);
                        signal = [signal triangle_signal(1:end-1)];

                end
            end
            
           % Append the last sample of the last segment to complete the signal
signal = [signal, signal(end)];
time = [time_vector, transition_time(end)];
% Plotting the full signal
plot(app.UIAxes, time, signal);
title(app.UIAxes, 'Generated Signal');
xlabel(app.UIAxes, 'Time (sec)');
ylabel(app.UIAxes, 'Amplitude');

% Refocus on the main application window to prevent it from minimizing
figure(app.UIFigure);
app.UIFigure.Visible = 'on';

% Ensure the application window is not minimized
if strcmp(app.UIFigure.WindowState, 'minimized')
    app.UIFigure.WindowState = 'normal';
end

% Performing an operation on the signal
operationchoice = questdlg('Would you like to perform any additional operation?', 'Additional Operations', 'Yes', 'No', 'No');

if strcmp(operationchoice, 'Yes')
    operations = {'Amplitude scaling', 'Time reversal', 'Time shift', 'Expanding the signal', 'Compressing the signal', 'Clipping the signal', 'The first derivative of the signal'};
    selectedoperation = listdlg('PromptString', 'Select an option:', 'ListString', operations, 'SelectionMode', 'single');

    switch selectedoperation
        case 1
            scale = str2double(inputdlg('Enter the scale value:', 'Input Dialog'));
            new_signal = scale * signal;

            plot(app.UIAxes2, time, new_signal);
            title(app.UIAxes2, 'New Signal');
            xlabel(app.UIAxes2, 'Time (sec)');
            ylabel(app.UIAxes2, 'Amplitude');
        case 2
            new_time = -1 .* time;

            plot(app.UIAxes2, new_time, signal);
            title(app.UIAxes2, 'New Signal');
            xlabel(app.UIAxes2, 'Time (sec)');
            ylabel(app.UIAxes2, 'Amplitude');
        case 3
            % Get the shift amount from the user
            shift_amount = str2double(inputdlg('Enter the shift amount:', 'Input Dialog'));

       
            new_time=time+shift_amount ; 
            

            plot(app.UIAxes2, new_time, signal);
            title(app.UIAxes2, 'New Signal');
            xlabel(app.UIAxes2, 'Time (sec)');
            ylabel(app.UIAxes2, 'Amplitude');
        case 4
            expanding_value = str2double(inputdlg('Enter expanding value:', 'Input Dialog'));
            new_time = time * (1 / expanding_value);
           
            plot(app.UIAxes2, time, new_signal);
            title(app.UIAxes2, 'New Signal');
            xlabel(app.UIAxes2, 'Time (sec)');
            ylabel(app.UIAxes2, 'Amplitude');
        case 5
            compressing_value = str2double(inputdlg('Enter compressing value:', 'Input Dialog'));
            new_signal=resample(signal, 1 ,compressing_value);
           
            plot(app.UIAxes2,time, new_signal);
            title(app.UIAxes2, 'New Signal');
            xlabel(app.UIAxes2, 'Time (sec)');
            ylabel(app.UIAxes2, 'Amplitude');
        case 6
            uvalue = app.uvalue_for_clippingEditField.Value;
            lvalue = app.lvalue_for_clippingEditField.Value;
            new_signal = max(lvalue, min(signal, uvalue));

            plot(app.UIAxes2, time, new_signal);
            title(app.UIAxes2, 'New Signal');
            xlabel(app.UIAxes2, 'Time (sec)');
            ylabel(app.UIAxes2, 'Amplitude');
        case 7
                            % Check if the signal has more than one element
                            if length(signal) > 1
                                % Initialize an array to store the differences
                                differences = zeros(1, length(signal) - 1);
                            
                                % Compute the differences between consecutive elements
                                for i = 1:length(signal) - 1
                                    differences(i) = signal(i+1) - signal(i);
                                end
                           
                                % Display the differences
                                %disp(differences);
                            else
                                disp('Signal has insufficient elements for computing differences.');
                            end
                            new_signal=differences 

                            
                            plot(app.UIAxes2,time(1:end-1),new_signal);
                            title(app.UIAxes2,'New Signal');
                            xlabel(app.UIAxes2,'Time (sec)');
                            ylabel(app.UIAxes2,'Amplitude');
    end

    % Refocus on the main application window to prevent it from minimizing after operation selection
    figure(app.UIFigure);
    app.UIFigure.Visible = 'on';

    % Ensure the application window is not minimized
    if strcmp(app.UIFigure.WindowState, 'minimized')
        app.UIFigure.WindowState = 'normal';
    end
end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Sampling_frequency_of_signalEditFieldLabel = uilabel(app.UIFigure);
            app.Sampling_frequency_of_signalEditFieldLabel.Text = 'Sampling_frequency_of_signal';
            app.Sampling_frequency_of_signalEditField = uieditfield(app.UIFigure, 'numeric');
            app.Sampling_frequency_of_signalEditField.Value = 15;
            app.Start_time_of_signalEditFieldLabel = uilabel(app.UIFigure);
            app.Start_time_of_signalEditFieldLabel.Text = 'Start_time_of_signal';
            app.Start_time_of_signalEditField = uieditfield(app.UIFigure, 'numeric');
            app.End_time_of_signalEditFieldLabel = uilabel(app.UIFigure);
            app.End_time_of_signalEditFieldLabel.Text = 'End_time_of_signal';
            app.End_time_of_signalEditField = uieditfield(app.UIFigure, 'numeric');
            app.End_time_of_signalEditField.Value = 5;
            app.number_of_break_points_of_signalEditFieldLabel = uilabel(app.UIFigure);
            app.number_of_break_points_of_signalEditFieldLabel.Text = 'number_of_break_points_of_signal';
            app.number_of_break_points_of_signalEditField = uieditfield(app.UIFigure, 'numeric');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SIGNALDropDownLabel = uilabel(app.UIFigure);
            app.SIGNALDropDownLabel.Text = 'SIGNAL';
            app.SIGNALDropDown = uidropdown(app.UIFigure);
            app.SIGNALDropDown.Items = {'DC_SIGNAL', 'RAMP_SIGNAL', 'POLYNOMIAL', 'EXPONENTIAL', 'SIN_SIGNAL', 'SINC_FUNCTION', 'TRIANGLE_PULSE'};
            app.SIGNALDropDown.Value = 'RAMP_SIGNAL';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Generated Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.uvalue_for_clippingEditFieldLabel = uilabel(app.UIFigure);
            app.uvalue_for_clippingEditFieldLabel.Text = 'uvalue_for_clipping';
            app.uvalue_for_clippingEditField = uieditfield(app.UIFigure, 'numeric');
            app.lvalue_for_clippingEditFieldLabel = uilabel(app.UIFigure);
            app.lvalue_for_clippingEditFieldLabel.Text = 'lvalue_for_clipping';
            app.lvalue_for_clippingEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'New Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = SIGNAL_PROJECT_LAST_UPDATE

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end